classdef TrafficTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        ProfileParametersTab       matlab.ui.container.Tab
        DatabasedprofilePanel      matlab.ui.container.Panel
        AverageTraveledDistancekmSpinner  matlab.ui.control.Spinner
        AverageTraveledDistancekmSpinnerLabel  matlab.ui.control.Label
        CarNumberSpinner           matlab.ui.control.Spinner
        CarNumberSpinnerLabel      matlab.ui.control.Label
        TrafficDataEditField       matlab.ui.control.EditField
        TrafficDataEditFieldLabel  matlab.ui.control.Label
        LoadButton_2               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        EVDataEditField            matlab.ui.control.EditField
        EVDataEditFieldLabel       matlab.ui.control.Label
        UserdefinedprofilePanel    matlab.ui.container.Panel
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        ProfileTypeDropDown        matlab.ui.control.DropDown
        ProfileTypeDropDownLabel   matlab.ui.control.Label
        RunProfileGeneratorTab     matlab.ui.container.Tab
        RunButton                  matlab.ui.control.Button
        FileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel     matlab.ui.control.Label
        NumberofdaysSpinner        matlab.ui.control.Spinner
        NumberofdaysSpinnerLabel   matlab.ui.control.Label
        ProfileViewerTab           matlab.ui.container.Tab
        GeneratedprofilesPanel     matlab.ui.container.Panel
        DaySlider                  matlab.ui.control.Slider
        DaySliderLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        P_EV = []; % Generated profile array
    end
    
    methods (Access = private)
        
        function [dist_arrival, dist_duration, dist_EkWh] = ...
            generate_distributions_UD(app)
            % Generate profile distributions from user defined data

            % Initialize distributions
            dist_arrival = [];
            dist_duration = [];
            dist_EkWh = [];
            % Define distribution parameters
            sigma = 0.25; % Sigma in quarter of an hour
            offset = 0.5; % Move means half an hour
            
            % Iterate for each shift defined in table
            for k = 1:height(app.UITable.Data)
                % Get shift
                shift = app.UITable.Data(k,:);
                % Put arrival and departure times in hour format
                arrival_t = hour(shift.Arrival) + minute(shift.Arrival)/60;
                departure_t = hour(shift.Departure) + minute(shift.Departure)/60;
                % Move to next day departures on shifts that happen before
                % the arrival
                if departure_t < arrival_t
                    departure_t = departure_t + 24;
                end
                
                % Build arrival and park duration means based on shift type
                switch shift.Shift
                    case "Office"
                        med_arrival = arrival_t;
                        med_duration = departure_t - arrival_t;
    
                    case "Factory"
                        med_arrival = arrival_t - offset;
                        med_duration = departure_t + offset - arrival_t;
    
                    case "Fleet"
                        med_arrival = arrival_t + offset;
                        med_duration = departure_t - offset - arrival_t;
    
                end    
                
                % Append arrival and duration distributions
                dist_arrival = [dist_arrival, makedist("Normal", med_arrival, sigma)]; %#ok<*AGROW> 
                dist_duration = [dist_duration, makedist("Normal", med_duration, sigma)];
                % Build energy to charge distributions based on the
                % estimated traveled distance. Considered 0.2 kWh/km
                dist_EkWh = [dist_EkWh, makedist("Normal", shift.Distance*0.2, shift.Distance*0.2*0.25)];
            end
        end


        function [dist_arrival, dist_duration, dist_EkWh] = ...
            generate_distributions_DB(app)
            % Generate profile distributions from existing traffic database

            % Read traffic data table
            opts = detectImportOptions(app.TrafficDataEditField.Value);
            Traffic = readtable(app.TrafficDataEditField.Value,opts);
            
            % Put arrival and departure times in hour format
            arrival = hour(Traffic.Arrival) + minute(Traffic.Arrival)/60;
            departure = hour(Traffic.Departure) + minute(Traffic.Departure)/60;
            % Move to next day departures on data if they happen before
            % arrival time
            departure(departure < arrival) = departure(departure < arrival) + 24;
            % Compute park duration
            duration = departure - arrival;
            
            % Build arrival and duration distributions
            dist_arrival = fitdist(arrival, "Kernel");
            dist_duration = fitdist(duration, "Kernel");
            
            % Build energy to charge distributions based on the
            % estimated traveled distance. Considered 0.2 kWh/km
            distance = app.AverageTraveledDistancekmSpinner.Value;
            dist_EkWh = makedist("Normal", distance*0.2, distance*0.2*0.25);
        end


        function profile = ...
            generate_profile(~, dist_arrival, dist_duration, EV_file, ...
            num_EV, dist_EkW)
            
            % Select number of cars with a 5% variation
            num_EV = round(random("normal", num_EV, num_EV*0.05));
            
            % Build profile table
            profile = table();
            % Generate arrival hours and ensure it stays in the 24h
            profile.Arrival = random(dist_arrival, num_EV, 1);
            profile.Arrival_Day = profile.Arrival >= 24;
            profile.Arrival(profile.Arrival_Day) = ...
                rem(profile.Arrival(profile.Arrival_Day),  24);
            % Generate departure hours and compute departure day
            profile.Departure = profile.Arrival + ...
                random(dist_duration, num_EV, 1);
            profile.Departure_Day = profile.Departure >= 24;
            profile.Departure(profile.Departure_Day) = ...
                rem(profile.Departure(profile.Departure_Day),  24);
            profile.Departure_Day = profile.Arrival_Day + profile.Departure_Day;
            % Format of arrival and departures
            profile.Arrival = datestr(profile.Arrival/24, 'HH:MM');
            profile.Departure = datestr(profile.Departure/24, 'HH:MM');
            % Load EV data from file and select cars
            opts = detectImportOptions(EV_file);
            EV_data = readtable(EV_file, opts);
            profile.EV = randsample(EV_data.Model,num_EV,true, ...
                EV_data.Share);
            % Compute kWh to charge
            profile.EkWh_Charge = random(dist_EkW, num_EV, 1);
            profile.EkWh_Charge(profile.EkWh_Charge < 0) = 0; % No negative charges

        end
        
        function update_PDF_plots(app, pdf_arrival, pdf_duration)
            % Update pdf plots
            cla(app.UIAxes);
            cla(app.UIAxes_2);

            t = 0:1/2:24;
            pd_ar = zeros(1, length(t));
            pd_du = zeros(1, length(t));
            for k = 1:length(pdf_arrival)
                pd_ar = pd_ar + pdf(pdf_arrival(k), t);
                pd_du = pd_du + pdf(pdf_duration(k), t);
            end
            pd_ar = pd_ar/sum(pd_ar);
            pd_du = pd_du/sum(pd_du);

            plot(app.UIAxes, t, pd_ar, '-k');
            plot(app.UIAxes_2, t, pd_du, '-k');
            
        end

        function update_hist_plot(app, day)
            % Update histogram plots
            cla(app.UIAxes_3);

            P_arrivals = app.P_EV(app.P_EV.Arrival_Day == day, :);
            arrivals = hour(P_arrivals.Arrival) + minute(P_arrivals.Arrival)/60;

            P_departures = app.P_EV(app.P_EV.Departure_Day == day, :);
            departures = hour(P_departures.Departure) + minute(P_departures.Departure)/60;

            histogram(app.UIAxes_3, arrivals, 0:24, 'FaceColor','cyan', 'FaceAlpha',0.5);
            hold(app.UIAxes_3, 'on');
            histogram(app.UIAxes_3, departures, 0:24, 'FaceColor','red', 'FaceAlpha',0.5);
            legend(app.UIAxes_3, ["Arrivals", "Departures"], "Location","best");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatabasedprofilePanel.Visible='off';
            app.UserdefinedprofilePanel.Visible = "off";
            app.RunButton.Enable='off';
            app.FileNameEditField.Enable = "off";
            app.GeneratedprofilesPanel.Visible = "off";
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Add row to user-defined table with default values
            d1 = categorical("Office", {'Office','Factory','Fleet'}, ...
                'Ordinal',true);
            d2 = datetime("08:00", "Format", "HH:mm");
            d3 = datetime("14:00", "Format", "HH:mm");
            d4 = 10;
            d5 = 100;
            d6 = "Data\EV\Default_EV.csv";
            tdata = table(d1,d2,d3,d4,d5,d6,'VariableNames', ...
                app.UITable.ColumnName);
            app.UITable.Data=[app.UITable.Data; tdata];
            app.RunButton.Enable='on';
            app.FileNameEditField.Enable = "on";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Delete row from user-defined table
            [row, ~] = size(app.UITable.Data);
            app.UITable.Data(row,:)=[];
            if height(app.UITable.Data) == 0
                app.RunButton.Enable='off';
                app.FileNameEditField.Enable = "off";
            end
        end

        % Value changed function: ProfileTypeDropDown
        function ProfileTypeDropDownValueChanged(app, event)
            % ProfileTypeDropDown data to show
            switch(app.ProfileTypeDropDown.Value)
                case 'Profile type'
                    app.DatabasedprofilePanel.Visible='off';
                    app.UserdefinedprofilePanel.Visible = "off";
                    app.RunButton.Enable='off';
                    app.FileNameEditField.Enable = "off";
                case 'Data-based'
                    app.DatabasedprofilePanel.Visible='on';
                    app.UserdefinedprofilePanel.Visible = "off";
                    app.RunButton.Enable='on';
                    app.FileNameEditField.Enable = "on";
                case 'User-defined'
                    app.DatabasedprofilePanel.Visible='off';
                    app.UserdefinedprofilePanel.Visible = "on";
                    if height(app.UITable.Data) == 0
                        app.RunButton.Enable='off';
                        app.FileNameEditField.Enable = "off";
                    else
                        app.RunButton.Enable='on';
                        app.FileNameEditField.Enable = "on";
                    end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Main program of the application
            app.FileNameEditField.Enable = "off";
            app.NumberofdaysSpinner.Enable = "off";
            app.RunButton.Enable='off';

            % STEP 1 -> Build the traffic distributions
            switch app.ProfileTypeDropDown.Value

                case "User-defined"
                    % Arrival, departure and energy distibutions
                    [dist_arrival, dist_duration, dist_EkWh] = ...
                    app.generate_distributions_UD();
                    % EV number
                    num_EVs = app.UITable.Data.Vehicles;
                    % EV info files
                    EV_files = app.UITable.Data.Vehicle_Distribution;

                case "Data-based"
                    % Arrival, departure and energy distibutions
                    [dist_arrival, dist_duration, dist_EkWh] = ...
                    app.generate_distributions_DB();
                    % EV number
                    num_EVs = app.CarNumberSpinner.Value;
                    % EV info files
                    EV_files = app.EVDataEditField.Value;

            end
            
            % STEP 2 -> Build profiles based on distributions
            Profile_EV = table();
            % For selected number of days and shifts (if user-defined)
            for n_day = 1:app.NumberofdaysSpinner.Value
                for dist_i = 1:length(dist_arrival)
                    
                    if length(dist_arrival) == 1
                        EV_file = EV_files;
                    else
                        EV_file = EV_files(dist_i);
                    end
                    
                    % Generate profile
                    profile = app.generate_profile(dist_arrival(dist_i), ...
                        dist_duration(dist_i), EV_file, num_EVs(dist_i), ...
                        dist_EkWh(dist_i));
                    % Update day number
                    profile.Arrival_Day = profile.Arrival_Day + n_day;
                    profile.Departure_Day = profile.Departure_Day + n_day;
                    % Append to profiles
                    Profile_EV = [Profile_EV; profile];

                end
            end
            % Sort in days for storing purposes
            Profile_EV = sortrows(Profile_EV, [2, 1]);
            
            % STORE -> Save built traffic profile
            % Find filename
            if app.FileNameEditField.Value == ""
                file_name = app.FileNameEditField.Placeholder;
            else
                file_name = app.FileNameEditField.Value;
            end
            % Build result directory
            if ~exist("Results_Tool", "dir")
                mkdir("Results_Tool")
            end
            % Don't overide existing results
            k = 1;
            temp_fil = file_name;
            while exist(strcat('Results_Tool\', temp_fil, '.mat'),"file")
                temp_fil = strcat(file_name, '_', num2str(k));
                k = k+1;
            end
            file_name = temp_fil;
            % Store
            save(strcat('Results_Tool\', file_name, '.mat'), "Profile_EV")

            % VISUALIZATION
            app.P_EV = Profile_EV;
            
            % Update plots
            app.update_PDF_plots(dist_arrival, dist_duration)
            app.update_hist_plot(1)

            app.GeneratedprofilesPanel.Visible = "on";
            % Handle day selection slider
            if app.NumberofdaysSpinner.Value > 1
                app.DaySlider.Visible = "on";
                app.DaySliderLabel.Visible = "on";
                app.DaySlider.Limits = [1, app.NumberofdaysSpinner.Value];
                app.DaySlider.MajorTicks = ...
                    1:app.NumberofdaysSpinner.Value;
                app.DaySlider.MinorTicks = [];
            else
                app.DaySlider.Visible = "off";
                app.DaySliderLabel.Visible = "off";
            end
            
            % Enable options again
            app.FileNameEditField.Enable = "on";
            app.NumberofdaysSpinner.Enable = "on";
            app.RunButton.Enable='on';
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelect(app, event)
            % Load file on user-defined table
            if size(event.Indices,1) == 1 && event.Indices(2) == 6
                [file,path] = uigetfile({'*.csv'; '*.mat'}, "Select traffic data");
                if ~isequal(file,0)
                    app.UITable.Data(event.Indices(1), 6) = cellstr(fullfile(path, file));
                end
            end
            
        end

        % Button pushed function: LoadButton_2
        function Load_Traffic(app, event)
            % Load Traffic data
            [file,path] = uigetfile({'*.csv'; '*.mat'}, "Select traffic data");
            if ~isequal(file,0)
                app.TrafficDataEditField.Value = fullfile(path, file);
            end
        end

        % Button pushed function: LoadButton
        function LoadEV(app, event)
            % Load EV data
            [file,path] = uigetfile({'*.csv'; '*.mat'}, "Select EV data");
            if ~isequal(file,0)
                app.EVDataEditField.Value = fullfile(path, file);
            end
        end

        % Value changed function: DaySlider
        function changeDaySlider(app, event)
            value = app.DaySlider.Value;
            % Determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % Move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);

            app.update_hist_plot(value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProfileParametersTab = uitab(app.TabGroup);
            app.ProfileParametersTab.Title = 'Profile Parameters';
            app.ProfileTypeDropDownLabel = uilabel(app.ProfileParametersTab);
            app.ProfileTypeDropDownLabel.Text = 'Profile Type';
            app.ProfileTypeDropDown = uidropdown(app.ProfileParametersTab);
            app.ProfileTypeDropDown.Items = {'Profile type', 'User-defined', 'Data-based'};
            app.ProfileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileTypeDropDownValueChanged, true);
            app.ProfileTypeDropDown.Value = 'Profile type';
            app.UserdefinedprofilePanel = uipanel(app.ProfileParametersTab);
            app.UserdefinedprofilePanel.Title = 'User defined profile';
            app.UITable = uitable(app.UserdefinedprofilePanel);
            app.UITable.ColumnName = {'Shift'; 'Arrival'; 'Departure'; 'Vehicles'; 'Distance'; 'Vehicle_Distribution'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelect, true);
            app.Button = uibutton(app.UserdefinedprofilePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UserdefinedprofilePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.DatabasedprofilePanel = uipanel(app.ProfileParametersTab);
            app.DatabasedprofilePanel.Title = 'Data based profile';
            app.EVDataEditFieldLabel = uilabel(app.DatabasedprofilePanel);
            app.EVDataEditFieldLabel.Text = 'EV Data';
            app.EVDataEditField = uieditfield(app.DatabasedprofilePanel, 'text');
            app.EVDataEditField.Editable = 'off';
            app.EVDataEditField.Value = 'Data\EV\BCN_AMB_EV_Info.csv';
            app.LoadButton = uibutton(app.DatabasedprofilePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEV, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'fig', '215008-200.png');
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.DatabasedprofilePanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @Load_Traffic, true);
            app.LoadButton_2.Icon = fullfile(pathToMLAPP, 'fig', '288-2884986_traffic-jam-icon-traffic-icon.jpg');
            app.LoadButton_2.Text = 'Load';
            app.TrafficDataEditFieldLabel = uilabel(app.DatabasedprofilePanel);
            app.TrafficDataEditFieldLabel.Text = 'Traffic Data';
            app.TrafficDataEditField = uieditfield(app.DatabasedprofilePanel, 'text');
            app.TrafficDataEditField.Editable = 'off';
            app.TrafficDataEditField.Value = 'Data\Traffic\BCN_AMB.csv';
            app.CarNumberSpinnerLabel = uilabel(app.DatabasedprofilePanel);
            app.CarNumberSpinnerLabel.Text = 'Car Number';
            app.CarNumberSpinner = uispinner(app.DatabasedprofilePanel);
            app.CarNumberSpinner.Limits = [1 100000];
            app.CarNumberSpinner.Value = 10;
            app.AverageTraveledDistancekmSpinnerLabel = uilabel(app.DatabasedprofilePanel);
            app.AverageTraveledDistancekmSpinnerLabel.Text = 'Average Traveled Distance (km)';
            app.AverageTraveledDistancekmSpinner = uispinner(app.DatabasedprofilePanel);
            app.AverageTraveledDistancekmSpinner.Limits = [1 5000];
            app.AverageTraveledDistancekmSpinner.Value = 20;
            app.RunProfileGeneratorTab = uitab(app.TabGroup);
            app.RunProfileGeneratorTab.Title = 'Run Profile Generator';
            app.NumberofdaysSpinnerLabel = uilabel(app.RunProfileGeneratorTab);
            app.NumberofdaysSpinnerLabel.Text = 'Number of days';
            app.NumberofdaysSpinner = uispinner(app.RunProfileGeneratorTab);
            app.NumberofdaysSpinner.Limits = [1 1000];
            app.NumberofdaysSpinner.Value = 1;
            app.FileNameEditFieldLabel = uilabel(app.RunProfileGeneratorTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RunProfileGeneratorTab, 'text');
            app.FileNameEditField.Placeholder = 'Res_Tool';
            app.RunButton = uibutton(app.RunProfileGeneratorTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'fig', '189638.png');
            app.RunButton.Text = 'Run';
            app.ProfileViewerTab = uitab(app.TabGroup);
            app.ProfileViewerTab.Title = 'Profile Viewer';
            app.GeneratedprofilesPanel = uipanel(app.ProfileViewerTab);
            app.GeneratedprofilesPanel.Title = 'Generated profiles';
            app.UIAxes_3 = uiaxes(app.GeneratedprofilesPanel);
            title(app.UIAxes_3, 'Day Cases')
            xlabel(app.UIAxes_3, 't [h]')
            ylabel(app.UIAxes_3, 'EV count')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 24];
            app.UIAxes_2 = uiaxes(app.GeneratedprofilesPanel);
            title(app.UIAxes_2, 'Duration PDF')
            xlabel(app.UIAxes_2, 't [h]')
            ylabel(app.UIAxes_2, 'p(x)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 24];
            app.UIAxes = uiaxes(app.GeneratedprofilesPanel);
            title(app.UIAxes, 'Arrival PDF')
            xlabel(app.UIAxes, 't [h]')
            ylabel(app.UIAxes, 'p(x)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 24];
            app.DaySliderLabel = uilabel(app.GeneratedprofilesPanel);
            app.DaySliderLabel.Text = 'Day';
            app.DaySlider = uislider(app.GeneratedprofilesPanel);
            app.DaySlider.Limits = [1 100];
            app.DaySlider.ValueChangedFcn = createCallbackFcn(app, @changeDaySlider, true);
            app.DaySlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fig', 'GOI-MONDRAGON-1.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'fig', '1200px-Logo_Grupo_Ormazabal.svg.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrafficTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end